classdef beamforming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        BeamformingSimulationLabel   matlab.ui.control.Label
        TargetPanel                  matlab.ui.container.Panel
        xCoordSpinnerLabel           matlab.ui.control.Label
        xCoordTargetSpinner          matlab.ui.control.Spinner
        yCoordSpinnerLabel           matlab.ui.control.Label
        yCoordTargetSpinner          matlab.ui.control.Spinner
        AmplitudeGaugeLabel          matlab.ui.control.Label
        AmplitudeGauge               matlab.ui.control.Gauge
        SourcePanel                  matlab.ui.container.Panel
        NumberofsourcesSpinnerLabel  matlab.ui.control.Label
        NumberOfSourcesSpinner       matlab.ui.control.Spinner
        SourceCoord                  matlab.ui.control.Table
        CalcButton                   matlab.ui.control.StateButton
        SourceCharacteristicsPanel   matlab.ui.container.Panel
        DampingDropDownLabel         matlab.ui.control.Label
        DampingDropDown              matlab.ui.control.DropDown
        FixedDirectionCheckBox       matlab.ui.control.CheckBox
        AngledegreeEditFieldLabel    matlab.ui.control.Label
        AngleDegreeEditField         matlab.ui.control.NumericEditField
        DampingDiagram               matlab.ui.control.UIAxes
        StoppButton                  matlab.ui.control.Button
        BeamformingCheckBox          matlab.ui.control.CheckBox
    end


    properties (Access = private)
        x_target = 25; % target x coordinate
        y_target = 25; % target y coordinate
        x_sources = [10]; % sources x coordinate
        y_sources = [10]; % sources y coordinate
        nr_sources = 1;
        damping = 1; % type of damping
        fixed_direction = 0; % fixed direction for damping in degrees
        % space and time parameters
        dx = 0.25;
        x_min = 0;
        x_max = 50;
        dy = 0.25;
        y_min = 0;
        y_max = 50;
        dt = 0.05;
        t_min = 0;
        t_max = 2*pi;
        lambda = 2*pi;
        x;
        y;
    end

    methods (Access = private)
    
        function results = main(app)         
            % get actual values
            app.x_target = app.xCoordTargetSpinner.Value;
            app.y_target = app.yCoordTargetSpinner.Value;
            app.getSourceCoord();
            
            [app.x, app.y] = meshgrid(app.x_min:app.dx:app.x_max, ...
                app.y_min:app.dy:app.y_max);
            distance = sqrt((app.x_target-app.x_sources).^2 ...
                +(app.y_target-app.y_sources).^2);
            
            app.computeDelay(distance);
            
            if app.BeamformingCheckBox.Value
                phaseshift = distance-floor(distance/app.lambda)*app.lambda;
            else
                phaseshift = zeros(app.nr_sources);
            end
            
            phi = zeros(app.nr_sources, 1);
            if app.FixedDirectionCheckBox.Value
                phi(:) = app.fixed_direction/360*2*pi;
            else
                for i = 1:app.nr_sources
                    phi(i) = app.computeAngle(app.x_sources(i), app.y_sources(i), ...
                        app.x_target, app.y_target);
                end
            end
            
            minimum = zeros(size(app.x, 1), size(app.y, 1));
            maximum = zeros(size(app.x, 1), size(app.y, 1));
            gain = ones(size(app.x, 1), size(app.y, 1));
            for t = app.t_min:app.dt:app.t_max+app.dt
                all = 0;
                % compute wave for each source and overlay
                for i = 1:app.nr_sources
                    % compute distance relative to wave source
                    positions = sqrt((app.x-app.x_sources(i)).^2+(app.y-app.y_sources(i)).^2);
                    
                    if app.damping ~= 1
                        % compute angle relativ to wave source
                        psi = app.computeAngle(app.x_sources(i), app.y_sources(i), app.x, app.y);
                        % compute angle gain
                        if app.damping == 3
                            gain = app.angleDampingAsym(psi, phi(i));
                        else
                            gain = (1+cos(2*(psi-phi(i))))/2;
                        end
                    end
        
                    % compute actual wave
                    part = sin(positions-t-phaseshift(i)).*gain;
                    all = all+part;
                end
    
                % check for each point, whether the overlay reaches a new min/max
                if t == app.t_min
                    minimum = all;
                    maximum = all;
                else
                    minimum = app.getMinMatrix(minimum, all);
                    maximum = app.getMaxMatrix(maximum, all);
                end
            end
            amp = maximum-minimum;
            
            surf(app.UIAxes,app.x, app.y, amp);
            rotate3d(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            plot3(app.UIAxes,[app.x_target app.x_target], [app.y_target app.y_target], ...
                [0 2*app.nr_sources+1], 'r');
            for i = 1:app.nr_sources
                plot3(app.UIAxes,[app.x_sources(i) app.x_sources(i)], ...
                    [app.y_sources(i) app.y_sources(i)], [0 2*app.nr_sources+1], 'g');
            end
            hold(app.UIAxes,'off');
            
            % find amplitude at target point
            k_x = (app.x_target-app.x_min)/app.dx+1;
            k_y = (app.y_target-app.y_min)/app.dy+1;           
            app.AmplitudeGauge.Value = amp(k_x,k_y);
            set(app.AmplitudeGauge, 'Limits', [0 2*app.nr_sources]);
        end
        
        function phi = computeAngle(app,x_s, y_s, x_t, y_t)
             s = size(x_t);
             phi = zeros(s);
             for i = 1:s(1)
                for j = 1:s(2)
                    delta_x = x_t(i, j)-x_s;
                    delta_y = y_t(i, j)-y_s;

                    phi(i, j) = atan(delta_y/delta_x);

                    if delta_x <0
                        if delta_y < 0
                            phi(i, j) = phi(i, j)-pi;
                        else
                            phi(i, j) = pi+phi(i, j);
                        end
                    end
                end
             end
        end
        
        function results = getMinMatrix(app,x1, x2)
            results = zeros(size(x1));    
            for i = 1:size(x1, 1)
                for j = 1:size(x1, 2)
                    results(i, j) = min(x1(i, j), x2(i, j)); 
                end
            end
        end
        
        function results = getMaxMatrix(app,x1, x2)
             results = zeros(size(x1));
    
             for i = 1:size(x1, 1)
                for j = 1:size(x1, 2)
                    results(i, j) = max(x1(i, j), x2(i, j)); 
                end
             end            
        end
        
        function gain = angleDampingAsym(app,psi, phi)
            piH = pi/2;
            s = size(psi);
    
            gain = zeros(s);
    
            for i = 1:s(1)
                for j = 1:s(2)
                    if (phi <= piH) && (phi >= -piH)
                        if (psi(i, j) <= phi+piH) && (psi(i, j) >= phi-piH)
                            gain(i, j) = (1+cos(2*(psi(i, j)-phi)))/2;
                        end
                    elseif phi > piH
                        if (psi(i, j) >= phi-piH) || (psi(i, j) <= -(2*pi-piH-phi))
                            gain(i, j) = (1+cos(2*(psi(i, j)-phi)))/2;
                        end
                     else
                        if (psi(i, j) <= phi+piH) || (psi(i, j) >= (2*pi-pi/2-phi))
                            gain(i, j) = (1+cos(2*(psi(i, j)-phi)))/2;
                        end
                    end
                end
            end
        end
        
        
        function results = refreshDampingPlot(app)
            
            if app.damping ~= 1
                
                phi = -180+app.fixed_direction:0.1:180+app.fixed_direction;
                d = (1-(1+cos(2*((phi-app.fixed_direction)/360*2*pi)))/2);
                
                if app.damping == 3
                    d(1:900) = 1;
                    d(size(d, 2)-900:size(d, 2)) = 1;
                end
                    
                plot(app.DampingDiagram, phi, d);
            else
                plot(app.DampingDiagram, [-180 180], [0 0]);
            end
            
        end
        
        
        function results = getSourceCoord(app)
            data = get(app.SourceCoord, 'data');
            app.x_sources = data(1:app.nr_sources, 2);
            app.y_sources = data(1:app.nr_sources, 3);
        end
        
        function results = computeDelay(app, distance)
            if app.BeamformingCheckBox.Value
                max_dist = max(distance);
                diff = max_dist-distance;
                delay = diff./app.lambda;
            else
                delay = zeros(app.nr_sources, 1);
            end
            
            data = get(app.SourceCoord, 'data');
            numbers = data(1:app.nr_sources, 1);
            X = data(1:app.nr_sources, 2);
            Y = data(1:app.nr_sources, 3);

            tdata = [numbers'; X'; Y'; delay'];
            tdata = transpose(tdata);
            set(app.SourceCoord, 'Data', tdata, 'ColumnName', ...
                {'Number'; 'x-Coord'; 'y-Coord'; 'delay'}, 'ColumnWidt', ...
                {60 60 60 60}, 'ColumnEditable', [false true true false]);
            drawnow;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set initial sources
            tdata = [1; app.x_sources; app.y_sources; 0];
            tdata = transpose(tdata);
            set(app.SourceCoord, 'Data', tdata, 'ColumnName', ...
                {'Number'; 'x-Coord'; 'y-Coord'; 'delay'}, 'ColumnWidt', ...
                {60 60 60 60}, 'ColumnEditable', [false true true false]);
            drawnow;
            
            set(app.FixedDirectionCheckBox, 'Enable', 'off');
            set(app.AngleDegreeEditField, 'Enable', 'off');
            
            set(app.DampingDropDown, 'ItemsData', [1 2 3]);
            
            refreshDampingPlot(app);
            
            app.main();
        end

        % Value changed function: CalcButton
        function ValueChangeCalcButton(app, event)
            app.main();
            app.CalcButton.Value = false;
        end

        % Value changed function: DampingDropDown
        function DampingDropDownValueChanged(app, event)
            app.damping = app.DampingDropDown.Value;
            
            if app.damping ~= 1
                set(app.FixedDirectionCheckBox, 'Enable', 'on');
            else
                set(app.FixedDirectionCheckBox, 'Enable', 'off', 'Value', 0);
                set(app.AngleDegreeEditField, 'Enable', 'off', 'Value', 0);
            end
            
            refreshDampingPlot(app);
        end

        % Value changed function: FixedDirectionCheckBox
        function FixedDirectionCheckBoxValueChanged(app, event)
            value = app.FixedDirectionCheckBox.Value;
            
            if value == 0
                set(app.AngleDegreeEditField, 'Enable', 'off', 'Value', 0);
            else
                set(app.AngleDegreeEditField, 'Enable', 'on');
            end
            
            app.fixed_direction = 0;
            refreshDampingPlot(app);
        end

        % Button pushed function: StoppButton
        function StoppButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: AngleDegreeEditField
        function AngleDegreeEditFieldValueChanged(app, event)
            app.fixed_direction = app.AngleDegreeEditField.Value;
            refreshDampingPlot(app);
        end

        % Value changed function: NumberOfSourcesSpinner
        function NumberOfSourcesSpinnerValueChanged(app, event)
            data = get(app.SourceCoord, 'data');
            
            numbers = data(1:app.nr_sources, 1);
            app.x_sources = data(1:app.nr_sources, 2);
            app.y_sources = data(1:app.nr_sources, 3);
            delays = data(1:app.nr_sources, 4);
            
            app.nr_sources = app.NumberOfSourcesSpinner.Value;

            numbers = [numbers' app.nr_sources];
            app.x_sources = [app.x_sources' 0];
            app.y_sources = [app.y_sources' 0];
            delays = [delays' 0];
            
            tdata = [numbers; app.x_sources; app.y_sources; delays];
            tdata = transpose(tdata);
            set(app.SourceCoord, 'Data', tdata, 'ColumnName', ...
                {'Number'; 'x-Coord'; 'y-Coord'; 'delay'}, 'ColumnWidt', ...
                {60 60 60 60}, 'ColumnEditable', [false true true false]);
            drawnow;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BeamformingSimulationLabel = uilabel(app.UIFigure);
            app.BeamformingSimulationLabel.Text = 'Beamforming Simulation';
            app.TargetPanel = uipanel(app.UIFigure);
            app.TargetPanel.Title = 'Target';
            app.xCoordSpinnerLabel = uilabel(app.TargetPanel);
            app.xCoordSpinnerLabel.Text = 'x-Coord.';
            app.xCoordTargetSpinner = uispinner(app.TargetPanel);
            app.xCoordTargetSpinner.Limits = [0 50];
            app.xCoordTargetSpinner.Value = 25;
            app.yCoordSpinnerLabel = uilabel(app.TargetPanel);
            app.yCoordSpinnerLabel.Text = 'y-Coord.';
            app.yCoordTargetSpinner = uispinner(app.TargetPanel);
            app.yCoordTargetSpinner.Limits = [0 50];
            app.yCoordTargetSpinner.Value = 25;
            app.AmplitudeGaugeLabel = uilabel(app.TargetPanel);
            app.AmplitudeGaugeLabel.Text = 'Amplitude';
            app.AmplitudeGauge = uigauge(app.TargetPanel, 'circular');
            app.SourcePanel = uipanel(app.UIFigure);
            app.SourcePanel.Title = 'Source';
            app.NumberofsourcesSpinnerLabel = uilabel(app.SourcePanel);
            app.NumberofsourcesSpinnerLabel.Text = 'Number of sources';
            app.NumberOfSourcesSpinner = uispinner(app.SourcePanel);
            app.NumberOfSourcesSpinner.Limits = [1 10];
            app.NumberOfSourcesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberOfSourcesSpinnerValueChanged, true);
            app.NumberOfSourcesSpinner.Value = 1;
            app.SourceCoord = uitable(app.SourcePanel);
            app.SourceCoord.ColumnName = '';
            app.SourceCoord.RowName = {};
            app.CalcButton = uibutton(app.UIFigure, 'state');
            app.CalcButton.ValueChangedFcn = createCallbackFcn(app, @ValueChangeCalcButton, true);
            app.CalcButton.Text = 'Calc';
            app.SourceCharacteristicsPanel = uipanel(app.UIFigure);
            app.SourceCharacteristicsPanel.Title = 'Source characteristics';
            app.DampingDropDownLabel = uilabel(app.SourceCharacteristicsPanel);
            app.DampingDropDownLabel.Text = 'Damping';
            app.DampingDropDown = uidropdown(app.SourceCharacteristicsPanel);
            app.DampingDropDown.Items = {'None', 'angular symmetric', 'angular assymetric'};
            app.DampingDropDown.ValueChangedFcn = createCallbackFcn(app, @DampingDropDownValueChanged, true);
            app.DampingDropDown.Value = 'None';
            app.FixedDirectionCheckBox = uicheckbox(app.SourceCharacteristicsPanel);
            app.FixedDirectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedDirectionCheckBoxValueChanged, true);
            app.FixedDirectionCheckBox.Text = 'Fixed direction';
            app.AngledegreeEditFieldLabel = uilabel(app.SourceCharacteristicsPanel);
            app.AngledegreeEditFieldLabel.Text = 'Angle [degree]';
            app.AngleDegreeEditField = uieditfield(app.SourceCharacteristicsPanel, 'numeric');
            app.AngleDegreeEditField.Limits = [-180 180];
            app.AngleDegreeEditField.RoundFractionalValues = 'on';
            app.AngleDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @AngleDegreeEditFieldValueChanged, true);
            app.DampingDiagram = uiaxes(app.SourceCharacteristicsPanel);
            title(app.DampingDiagram, 'Damping')
            xlabel(app.DampingDiagram, 'Direction [Degree]')
            ylabel(app.DampingDiagram, 'Damping')
            app.StoppButton = uibutton(app.UIFigure, 'push');
            app.StoppButton.ButtonPushedFcn = createCallbackFcn(app, @StoppButtonPushed, true);
            app.StoppButton.Text = 'Stopp';
            app.BeamformingCheckBox = uicheckbox(app.UIFigure);
            app.BeamformingCheckBox.Text = 'Beamforming';
        end
    end

    methods (Access = public)

        % Construct app
        function app = beamforming

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end