classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        targetpointSliderLabel  matlab.ui.control.Label
        targetpointSlider       matlab.ui.control.Slider
    end


    properties (Access = public)
        target = 10;
    end

    methods (Access = public)
    
        function results = main(app)
            x = linspace(0,24*pi,1000);
            sources = [0 60];
            dist = sources - app.target;
            for i=1:100
               x1 = x(1:i*10);
               x2 = x(1001-i*10:end);
               x3 = intersect(x1,x2);
               sp1 = x1 - sources(1) + dist(1) +(pi/2);
               sp2 = x2 - sources(2) + dist(2) +(pi/2);
               sp3 = x3 - sources(1) + dist(1) +(pi/2);
               sp4 = x3 - sources(2) + dist(2) +(pi/2);
               s1 = sin(sp1);
               s2 = sin(sp2);
               s3 = sin(sp3)+sin(sp4);
               plot(app.UIAxes,x1,s1);
               ax = app.UIAxes;
               ax.XLim = [0 max(x)];
               ax.YLim = [-2 2];
               hold(app.UIAxes,'on')
               plot(app.UIAxes,x2,s2);
               hold(app.UIAxes,'off')
               
               plot(app.UIAxes2,app.target,2,'r.','markers',22);
               ax = app.UIAxes2;
               ax.XLim = [0 max(x)];
               ax.YLim = [-2 2];
               ax.XTick = [1:1:max(x)];
               hold(app.UIAxes2,'on')
               plot(app.UIAxes2,x3,s3);               
               hold(app.UIAxes2,'off')
               pause(0.4);
             end
        end
        
        function results = plot(app)
            
        end
        
    end


    methods (Access = private)

        % Value changing function: targetpointSlider
        function targetpointSliderValueChanging(app, event)
            app.target = event.Value;
            app.main();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'base waves')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'combined waves, target point (red)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.targetpointSliderLabel = uilabel(app.UIFigure);
            app.targetpointSliderLabel.Text = 'target point';
            app.targetpointSlider = uislider(app.UIFigure);
            app.targetpointSlider.Limits = [0 60];
            app.targetpointSlider.ValueChangingFcn = createCallbackFcn(app, @targetpointSliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = testapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end